classdef EvolutiveSysAnalyze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CurrentRobotFitness             matlab.ui.control.UIAxes
        SensorActivation                matlab.ui.control.UIAxes
        AverageRobotFitness             matlab.ui.control.UIAxes
        LinearVelocity                  matlab.ui.control.UIAxes
        AngularVelocity                 matlab.ui.control.UIAxes
        RotationTime                    matlab.ui.control.UIAxes
        Image                           matlab.ui.control.Image
        SetFixedGenesLabel              matlab.ui.control.Label
        SensorActivationValue           matlab.ui.control.NumericEditField
        SensorActivationCheckBox        matlab.ui.control.CheckBox
        LinearVelocityCheckBox          matlab.ui.control.CheckBox
        RotationTimeCheckBox            matlab.ui.control.CheckBox
        AngularVelocityCheckBox         matlab.ui.control.CheckBox
        SensorAngleCheckBox             matlab.ui.control.CheckBox
        LinearVelocityValue             matlab.ui.control.NumericEditField
        RotationTimeValue               matlab.ui.control.NumericEditField
        AngularVelocityValue            matlab.ui.control.NumericEditField
        SensorAngleValue                matlab.ui.control.NumericEditField
        mLabel                          matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        dgsLabel                        matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        dgLabel                         matlab.ui.control.Label
        TypesofPressureLabel            matlab.ui.control.Label
        Labels                          matlab.ui.control.Label
        PredationCheckBox               matlab.ui.control.CheckBox
        BackMutationPreventionCheckBox  matlab.ui.control.CheckBox
        TypesofDiversityLabel           matlab.ui.control.Label
        PredationValue                  matlab.ui.control.NumericEditField
        genLabel                        matlab.ui.control.Label
        MutationCheckBox                matlab.ui.control.CheckBox
        MutationValue                   matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        CrossingCheckBox                matlab.ui.control.CheckBox
        ControlSimulationLabel          matlab.ui.control.Label
        NeutralizationCheckBox          matlab.ui.control.CheckBox
        NeutralizationValue             matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        SwitchStart                     matlab.ui.control.Switch
        SimulationStatus                matlab.ui.control.Lamp
        SwitchPause                     matlab.ui.control.Switch
        SavealltoPDFfileLabel           matlab.ui.control.Label
        GenesLabel                      matlab.ui.control.Label
        PopulationfitnessLabel          matlab.ui.control.Label
        SensorAngleLabel                matlab.ui.control.Label
        ipv4Label                       matlab.ui.control.Label
        ipv4EditField                   matlab.ui.control.EditField
        ROSstatus                       matlab.ui.control.Lamp
        ROSLabel                        matlab.ui.control.Label
        SimulationLabel                 matlab.ui.control.Label
        SavegraphimageLabel             matlab.ui.control.Label
        SelectGraphImage                matlab.ui.control.DropDown
        ConnectROSButton                matlab.ui.control.Button
        SensorAngle                     matlab.ui.control.UIAxes
        SaveImage                       matlab.ui.control.Button
        SavePDF                         matlab.ui.control.Button
    end

    
    properties (Access = private)
        SensorAnglePolar % Polar graph (Sensor Angle)
        myTimer
        infosub
        % Variables to plotting
        last5Fit = zeros(6,5);
        x    = 0;
        yFt  = zeros(7,1);
        yAv  = zeros(7,1);
        ySA  = zeros(7,1);
        yLV  = zeros(7,1);
        yAV  = zeros(7,1);
        yRT  = zeros(7,1);
        ySAn = zeros(7,1);
        evolConfigPub;
        controlSimPub;
    end
    
    methods (Access = private)


        function [] = updatePlots(~, ~, info,app)
            disp('updatePlots');
            if(robotics.ros.internal.Global.isNodeActive)
                app.x(info.Generation) = info.Generation;

                infoDataName = {'SensorActivation' ;'LinearVelocity' ;'AngularVelocity'; 'RotationTime';'Fitness'; 'SensorAngle'};
                infoDataInitials = {'SA','LV','AV','RT','Ft','SAn'};
                %----- Update Average Fitness Value -----%
                for i=1:6% Update historic fitness matrix
                    for j=1:4
                        app.last5Fit(i,j) = app.last5Fit(i,j+1);
                    end
                    app.last5Fit(i,5) = info.Fitness(i);
                end

                % Update robotX average fitness
                nextYAv = zeros(7,1);
                for robotNum=1:6 % Update average values for the current population
                    nextYAv(robotNum) = sum(app.last5Fit(robotNum,:));

                    if(info.Generation>=5)
                        nextYAv(robotNum) = nextYAv(robotNum)/5;
                    else
                        nextYAv(robotNum) = nextYAv(robotNum)/info.Generation;
                    end
                end
                nextYAv(7) = sum(nextYAv(1:6))/6;

                if(info.Generation==1)
                    app.yAv = nextYAv;
                else
                    app.yAv = [app.yAv  nextYAv];
                end

                %----- Update chromosome graphs -----%
                for i=1:6
                    nextY = zeros(6,1);
                    eval(['nextY = info.' char(infoDataName(i)) ';']);

                    nextY = [nextY ; mean(nextY)];

                    if(info.Generation==1)
                        eval(['app.y' char(infoDataInitials(i)) ' = nextY;']);
                    else
                        eval([ 'app.y' char(infoDataInitials(i)) ' = [app.y' char(infoDataInitials(i)) ' nextY];']);
                    end
                end

                plotARF = plot(app.AverageRobotFitness,app.x,app.yAv);
                set(plotARF(7),'Color','r','linew',2);
                plotCRF = plot(app.CurrentRobotFitness,app.x,app.yFt);
                set(plotCRF(7),'Color','r','linew',2);
                plotSA = plot(app.SensorActivation,app.x,app.ySA);
                set(plotSA(7),'Color','r','linew',2);
                plotLV = plot(app.LinearVelocity,app.x,app.yLV);
                set(plotLV(7),'Color','r','linew',2);
                plotAV = plot(app.AngularVelocity,app.x,app.yAV);
                set(plotAV(7),'Color','r','linew',2);
                plotRT = plot(app.RotationTime,app.x,app.yRT);
                set(plotRT(7),'Color','r','linew',2);
                plotSAn = plot(app.SensorAngle,app.x,app.ySAn);
                set(plotSAn(7),'Color','r','linew',2);
                plotPolarSAn = plot(app.SensorAnglePolar,(app.ySAn.*(pi/180))',app.x,(-(app.ySAn).*(pi/180))',app.x,zeros(info.Generation,1),app.x);
                app.SensorAnglePolar.ThetaZeroLocation = 'top';
                set(plotPolarSAn(7),'Color','r','linew',2);
                set(plotPolarSAn(14),'Color','r','linew',2);
                set(plotPolarSAn(15),'Color','r','linew',2);
            end
        end
        
        function [] = publishEvolutiveSystemConfiguration(app)
            if(robotics.ros.internal.Global.isNodeActive)
                app.evolConfigPub = rospublisher('/evolutive_config','obstacle_avoidance_simulation/EvolutiveSystemConfiguration');
                msg = rosmessage(app.evolConfigPub);
                
                if(app.SensorActivationValue.Enable=="on")
                    msg.SensorActivation =  single(app.SensorActivationValue.Value);
                else
                    msg.SensorActivation =  single(-1);
                end
                if(app.LinearVelocityValue.Enable=="on")
                    msg.LinearVelocity =  single(app.LinearVelocityValue.Value);
                else
                    msg.LinearVelocity =  single(-1);
                end
                if(app.AngularVelocityValue.Enable=="on")
                    msg.AngularVelocity =  single(app.AngularVelocityValue.Value);
                else
                    msg.AngularVelocity =  single(-1);
                end
                if(app.RotationTimeValue.Enable=="on")
                    msg.RotationTime =  single(app.RotationTimeValue.Value);
                else
                    msg.RotationTime =  single(-1);
                end
                if(app.SensorAngleValue.Enable=="on")
                    msg.SensorAngle =  single(app.SensorAngleValue.Value);
                else
                    msg.SensorAngle =  single(-1);
                end
                if(app.PredationValue.Enable=="on")
                    msg.Predation =  int32(app.PredationValue.Value);
                else
                    msg.Predation =  int32(-1);
                end
                if(app.MutationValue.Enable=="on")
                    msg.Mutation =  single(app.MutationValue.Value);
                else
                    msg.Mutation =  single(-1);
                end
                if(app.NeutralizationValue.Enable=="on")
                    msg.Neutralization =  single(app.NeutralizationValue.Value);
                else
                    msg.Neutralization =  single(-1);
                end
                
                msg.BackMutationPrevention = app.BackMutationPreventionCheckBox.Value;
                msg.Crossing = app.CrossingCheckBox.Value;
                
                send(app.evolConfigPub,msg);
            end
        end
        
        function [] = publishControlSimulation(app)
            app.controlSimPub = rospublisher('/control_simulation','obstacle_avoidance_simulation/ControlSimulation');
            msg = rosmessage(app.controlSimPub);
            
            if(app.SwitchStart.Value=="Stop")
                msg.SimulationState = 0;
            else
                if(app.SwitchPause.Value=="Run")
                    msg.SimulationState = 1;
                else
                    msg.SimulationState = 2;
                end
            end
            
            send(app.controlSimPub,msg);
        end
%         function [] = updateEvolutiveSystem(app)
%             
%         end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Draw Sensor Angle Polar Plot
            app.SensorAnglePolar = polaraxes(app.UIFigure);
            app.SensorAnglePolar.Units = 'pixels';
            app.SensorAnglePolar.Position = [340 20 200 200];
            app.SensorAnglePolar.ThetaZeroLocation = 'top';
            
            % Check ROS Connection
            if(~robotics.ros.internal.Global.isNodeActive)
                rosinit(app.ipv4EditField.Value);
            end
            
            if(robotics.ros.internal.Global.isNodeActive)
%                 app.infosub = rossubscriber('/simulation_info',@app.updateinfo)
                app.ROSstatus.Color='g';
                app.ConnectROSButton.Text='Disconnect ROS';
                app.publishEvolutiveSystemConfiguration;
                % Create timer
%                 app.myTimer = timer('Period',1,...
%                 'ExecutionMode', 'fixedSpacing', ...
%                 'TasksToExecute', Inf);
%                 app.myTimer.TimerFcn = @(~,~)updateEvolutiveSystem(app);
%                 start(app.myTimer);
%                 rossubscriber('/simulation_info',@checkRosSub);
                app.infosub = rossubscriber('/simulation_info',{@app.updatePlots,app});
%                 batata = receive(app.infosub,300)
%                 app.infosub = rossubscriber('/simulation_info',@updateEvolutiveSystem);
%                 receive(app.infosub,100);
            else
                app.ROSstatus.Color='r';
                app.ConnectROSButton.Text='Connect ROS';
            end


        end

        % Button pushed function: ConnectROSButton
        function ConnectROSButtonPushed(app, event)
            robotics.ros.internal.Global.isNodeActive
            
            if(robotics.ros.internal.Global.isNodeActive==0)
                rosinit(app.ipv4EditField.Value);
                if(robotics.ros.internal.Global.isNodeActive==1)
                    app.ROSstatus.Color='g';
                    app.ConnectROSButton.Text='Disconnect ROS';
                    
                    app.infosub = rossubscriber('simulation_info');
                end
            else
                rosshutdown;
                if(robotics.ros.internal.Global.isNodeActive==0)
                    app.ROSstatus.Color='r';
                    app.ConnectROSButton.Text='Connect ROS';
                end
            end
        end

        % Value changed function: SwitchStart
        function SwitchStartValueChanged(app, event)
            value = app.SwitchStart.Value;
            
            app.publishControlSimulation;
            if(value=="Start")
                app.SwitchPause.Enable=1;
                app.SwitchPause.Value="Run";
                app.SimulationStatus.Color='g';
            else
                app.SwitchPause.Enable=0;
                app.SwitchPause.Value="Run";
                app.SimulationStatus.Color='r';
            end
            
        end

        % Value changed function: SwitchPause
        function SwitchPauseValueChanged(app, event)
            value = app.SwitchPause.Value;
            
            app.publishControlSimulation;
            if(value=="Run")
                app.SimulationStatus.Color='g';
            else
                app.SimulationStatus.Color='y';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            rosshutdown;
        end

        % Value changed function: SensorActivationCheckBox
        function SensorActivationCheckBoxValueChanged(app, event)
            value = app.SensorActivationCheckBox.Value;
            
            if(value)
                app.SensorActivationValue.Enable=1;
            else
                app.SensorActivationValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: LinearVelocityCheckBox
        function LinearVelocityCheckBoxValueChanged(app, event)
            value = app.LinearVelocityCheckBox.Value;
            
            if(value)
                app.LinearVelocityValue.Enable=1;
            else
                app.LinearVelocityValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: RotationTimeCheckBox
        function RotationTimeCheckBoxValueChanged(app, event)
            value = app.RotationTimeCheckBox.Value;
            
            if(value)
                app.RotationTimeValue.Enable=1;
            else
                app.RotationTimeValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: AngularVelocityCheckBox
        function AngularVelocityCheckBoxValueChanged(app, event)
            value = app.AngularVelocityCheckBox.Value;
            
            if(value)
                app.AngularVelocityValue.Enable=1;
            else
                app.AngularVelocityValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: SensorAngleCheckBox
        function SensorAngleCheckBoxValueChanged(app, event)
            value = app.SensorAngleCheckBox.Value;
            
            if(value)
                app.SensorAngleValue.Enable=1;
            else
                app.SensorAngleValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: BackMutationPreventionCheckBox
        function BackMutationPreventionCheckBoxValueChanged(app, event)
            %             value = app.BackMutationPreventionCheckBox.Value;
            app.publishEvolutiveSystemConfiguration;
        end

        % Value changed function: PredationCheckBox
        function PredationCheckBoxValueChanged(app, event)
            value = app.PredationCheckBox.Value;
            
            if(value)
                app.PredationValue.Enable=1;
            else
                app.PredationValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: MutationCheckBox
        function MutationCheckBoxValueChanged(app, event)
            value = app.MutationCheckBox.Value;
            
            if(value)
                app.MutationValue.Enable=1;
            else
                app.MutationValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: CrossingCheckBox
        function CrossingCheckBoxValueChanged(app, event)
            %             value = app.CrossingCheckBox.Value;
            app.publishEvolutiveSystemConfiguration;
        end

        % Value changed function: NeutralizationCheckBox
        function NeutralizationCheckBoxValueChanged(app, event)
            value = app.NeutralizationCheckBox.Value;
            
            if(value)
                app.NeutralizationValue.Enable=1;
            else
                app.NeutralizationValue.Enable=0;
                app.publishEvolutiveSystemConfiguration;
            end
            
        end

        % Value changed function: SensorActivationValue
        function SensorActivationValueValueChanged(app, event)
            %             value = app.SensorActivationValue.Value;
            if(app.SensorActivationValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: LinearVelocityValue
        function LinearVelocityValueValueChanged(app, event)
            %             value = app.LinearVelocityValue.Value;
            if(app.LinearVelocityValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: RotationTimeValue
        function RotationTimeValueValueChanged(app, event)
            %             value = app.RotationTimeValue.Value;
            if(app.RotationTimeValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: AngularVelocityValue
        function AngularVelocityValueValueChanged(app, event)
            %             value = app.AngularVelocityValue.Value;
            if(app.AngularVelocityValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: SensorAngleValue
        function SensorAngleValueValueChanged(app, event)
            %             value = app.SensorAngleValue.Value;
            if(app.SensorAngleValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: PredationValue
        function PredationValueValueChanged(app, event)
            %             value = app.PredationValue.Value;
            if(app.PredationValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: MutationValue
        function MutationValueValueChanged(app, event)
            %             value = app.MutationValue.Value;
            if(app.MutationValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Value changed function: NeutralizationValue
        function NeutralizationValueValueChanged(app, event)
            value = app.NeutralizationValue.Value;
            if(app.NeutralizationValue.Enable=='on')
                app.publishEvolutiveSystemConfiguration;
            end
        end

        % Button pushed function: SaveImage
        function SaveImageButtonPushed(app, event)
            imageName = inputdlg('Write the image name:');
            
            if(isempty(imageName))
                fig = figure('name',[app.SelectGraphImage.Value '.png']);
            else
                fig = figure('name',[imageName{1} '.png']);
            end

            figure(fig);
            subplot(211);
            
            %select a folder
            pathname = uigetdir(pwd, 'Select the folder where you want to save.');
            if(pathname~=0)
                pathname = [pathname '/'];
            
                switch(app.SelectGraphImage.Value)
                    case 'Current Fitness'
                        plotToSave = plot(app.x,app.yFt);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'Fitness');
                        xlabel(gca,'Generation');
                    case 'Average Fitness'
                        plotToSave = plot(app.x,app.yAv);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'Fitness');
                        xlabel(gca,'Generation');
                    case 'Sensor Activation'
                        plotToSave = plot(app.x,app.ySA);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'Distance (m)');
                        xlabel(gca,'Generation');
                    case 'Linear Velocity'
                        plotToSave = plot(app.x,app.yLV);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'L. Velocity (m/s)');
                        xlabel(gca,'Generation');
                    case 'Angular Velocity'
                        plotToSave = plot(app.x,app.yAV);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'A. Velocity (degree/s)');
                        xlabel(gca,'Generation');
                    case 'Rotation Time'
                        plotToSave = plot(app.x,app.yRT);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'Time (seg)');
                        xlabel(gca,'Generation');
                    case 'Sensor Angle'
                        plotToSave = plot(app.x,app.ySAn);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,app.SelectGraphImage.Value);
                        ylabel(gca,'Angle (degree)');
                        xlabel(gca,'Generation');
                end

                if(isempty(imageName))
                    saveas(fig,[ pathname app.SelectGraphImage.Value '.png']);
                else
                    saveas(fig,[ pathname imageName{1} '.png']);
                end
            end
        end

        % Button pushed function: SavePDF
        function SavePDFButtonPushed(app, event)
            imageName = inputdlg('Write the pdf name:');
            
            if(isempty(imageName))
                fig = figure('name',['allGraphs.pdf']);
            else
                fig = figure('name',[imageName{1} '.pdf']);
            end

            figure(fig);
            
            %select a folder
            pathname = uigetdir(pwd, 'Select the folder where you want to save.');
            if(pathname~=0)
                pathname = [pathname '/'];
            
                        subplot(511);
                        plotToSave = plot(app.x,app.yFt);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Current Fitness');
                        ylabel(gca,'Fitness');
                        xlabel(gca,'Generation');
                        
                        subplot(512);
                        plotToSave = plot(app.x,app.yAv);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Average Fitness');
                        ylabel(gca,'Fitness');
                        xlabel(gca,'Generation');

                        subplot(525);
                        plotToSave = plot(app.x,app.ySA);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Sensor Activation');
                        ylabel(gca,'Distance (m)');
                        xlabel(gca,'Generation');
                        
                        subplot(526);
                        plotToSave = plot(app.x,app.yLV);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Linear Velocity');
                        ylabel(gca,'L. Velocity (m/s)');
                        xlabel(gca,'Generation');

                        subplot(527);
                        plotToSave = plot(app.x,app.yAV);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Angular Velocity');
                        ylabel(gca,'A. Velocity (degree/s)');
                        xlabel(gca,'Generation');

                        subplot(528);
                        plotToSave = plot(app.x,app.yRT);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Rotation Time');
                        ylabel(gca,'Time (seg)');
                        xlabel(gca,'Generation');

                        subplot(529);
                        plotToSave = plot(app.x,app.ySAn);
                        set(plotToSave(7),'Color','r','linew',2);
                        title(gca,'Sensor Angle');
                        ylabel(gca,'Angle (degree)');
                        xlabel(gca,'Generation');
            end

            if(isempty(imageName))
                saveas(fig,[ pathname 'allGraphs.pdf']);
            else
                saveas(fig,[ pathname imageName{1} '.pdf']);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CurrentRobotFitness = uiaxes(app.UIFigure);
            title(app.CurrentRobotFitness, 'Current Robot Fitness')
            xlabel(app.CurrentRobotFitness, 'Generation')
            ylabel(app.CurrentRobotFitness, 'Fitness')
            app.SensorActivation = uiaxes(app.UIFigure);
            title(app.SensorActivation, 'Sensor Activation')
            xlabel(app.SensorActivation, 'Generation')
            ylabel(app.SensorActivation, 'Distance (m)')
            app.AverageRobotFitness = uiaxes(app.UIFigure);
            title(app.AverageRobotFitness, 'Average Robot Fitness')
            xlabel(app.AverageRobotFitness, 'Generation')
            ylabel(app.AverageRobotFitness, 'Fitness')
            app.LinearVelocity = uiaxes(app.UIFigure);
            title(app.LinearVelocity, 'Linear Velocity')
            xlabel(app.LinearVelocity, 'Generation')
            ylabel(app.LinearVelocity, 'L. Velocity (m/s)')
            app.AngularVelocity = uiaxes(app.UIFigure);
            title(app.AngularVelocity, 'Angular Velocity')
            xlabel(app.AngularVelocity, 'Generation')
            ylabel(app.AngularVelocity, 'A. Velocity (degree/s)')
            app.RotationTime = uiaxes(app.UIFigure);
            title(app.RotationTime, 'Rotation Time')
            xlabel(app.RotationTime, 'Generation')
            ylabel(app.RotationTime, 'Time (seg)')
            app.Image = uiimage(app.UIFigure);
            app.SetFixedGenesLabel = uilabel(app.UIFigure);
            app.SetFixedGenesLabel.Text = 'Set Fixed Genes';
            app.SensorActivationValue = uieditfield(app.UIFigure, 'numeric');
            app.SensorActivationValue.Limits = [0 Inf];
            app.SensorActivationValue.ValueChangedFcn = createCallbackFcn(app, @SensorActivationValueValueChanged, true);
            app.SensorActivationValue.Enable = 'off';
            app.SensorActivationCheckBox = uicheckbox(app.UIFigure);
            app.SensorActivationCheckBox.ValueChangedFcn = createCallbackFcn(app, @SensorActivationCheckBoxValueChanged, true);
            app.SensorActivationCheckBox.Text = 'Sensor Activation';
            app.LinearVelocityCheckBox = uicheckbox(app.UIFigure);
            app.LinearVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @LinearVelocityCheckBoxValueChanged, true);
            app.LinearVelocityCheckBox.Text = 'Linear Velocity';
            app.RotationTimeCheckBox = uicheckbox(app.UIFigure);
            app.RotationTimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotationTimeCheckBoxValueChanged, true);
            app.RotationTimeCheckBox.Text = 'Rotation Time';
            app.AngularVelocityCheckBox = uicheckbox(app.UIFigure);
            app.AngularVelocityCheckBox.ValueChangedFcn = createCallbackFcn(app, @AngularVelocityCheckBoxValueChanged, true);
            app.AngularVelocityCheckBox.Text = 'Angular Velocity';
            app.SensorAngleCheckBox = uicheckbox(app.UIFigure);
            app.SensorAngleCheckBox.ValueChangedFcn = createCallbackFcn(app, @SensorAngleCheckBoxValueChanged, true);
            app.SensorAngleCheckBox.Text = 'Sensor Angle';
            app.LinearVelocityValue = uieditfield(app.UIFigure, 'numeric');
            app.LinearVelocityValue.ValueChangedFcn = createCallbackFcn(app, @LinearVelocityValueValueChanged, true);
            app.LinearVelocityValue.Enable = 'off';
            app.RotationTimeValue = uieditfield(app.UIFigure, 'numeric');
            app.RotationTimeValue.Limits = [0 Inf];
            app.RotationTimeValue.ValueChangedFcn = createCallbackFcn(app, @RotationTimeValueValueChanged, true);
            app.RotationTimeValue.Enable = 'off';
            app.AngularVelocityValue = uieditfield(app.UIFigure, 'numeric');
            app.AngularVelocityValue.Limits = [0 Inf];
            app.AngularVelocityValue.ValueChangedFcn = createCallbackFcn(app, @AngularVelocityValueValueChanged, true);
            app.AngularVelocityValue.Enable = 'off';
            app.SensorAngleValue = uieditfield(app.UIFigure, 'numeric');
            app.SensorAngleValue.Limits = [0 90];
            app.SensorAngleValue.ValueChangedFcn = createCallbackFcn(app, @SensorAngleValueValueChanged, true);
            app.SensorAngleValue.Enable = 'off';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.msLabel = uilabel(app.UIFigure);
            app.msLabel.Text = 'm/s';
            app.dgsLabel = uilabel(app.UIFigure);
            app.dgsLabel.Text = 'dg/s';
            app.sLabel = uilabel(app.UIFigure);
            app.sLabel.Text = 's';
            app.dgLabel = uilabel(app.UIFigure);
            app.dgLabel.Text = 'dg';
            app.TypesofPressureLabel = uilabel(app.UIFigure);
            app.TypesofPressureLabel.Text = 'Types of Pressure';
            app.Labels = uilabel(app.UIFigure);
            app.Labels.Text = 'Labels';
            app.PredationCheckBox = uicheckbox(app.UIFigure);
            app.PredationCheckBox.ValueChangedFcn = createCallbackFcn(app, @PredationCheckBoxValueChanged, true);
            app.PredationCheckBox.Text = 'Predation';
            app.PredationCheckBox.Value = true;
            app.BackMutationPreventionCheckBox = uicheckbox(app.UIFigure);
            app.BackMutationPreventionCheckBox.ValueChangedFcn = createCallbackFcn(app, @BackMutationPreventionCheckBoxValueChanged, true);
            app.BackMutationPreventionCheckBox.Text = 'Back Mutation Prevention';
            app.BackMutationPreventionCheckBox.Value = true;
            app.TypesofDiversityLabel = uilabel(app.UIFigure);
            app.TypesofDiversityLabel.Text = 'Types of Diversity';
            app.PredationValue = uieditfield(app.UIFigure, 'numeric');
            app.PredationValue.Limits = [0 Inf];
            app.PredationValue.ValueChangedFcn = createCallbackFcn(app, @PredationValueValueChanged, true);
            app.PredationValue.Value = 10;
            app.genLabel = uilabel(app.UIFigure);
            app.genLabel.Text = 'gen.';
            app.MutationCheckBox = uicheckbox(app.UIFigure);
            app.MutationCheckBox.ValueChangedFcn = createCallbackFcn(app, @MutationCheckBoxValueChanged, true);
            app.MutationCheckBox.Text = 'Mutation';
            app.MutationCheckBox.Value = true;
            app.MutationValue = uieditfield(app.UIFigure, 'numeric');
            app.MutationValue.Limits = [0 100];
            app.MutationValue.ValueChangedFcn = createCallbackFcn(app, @MutationValueValueChanged, true);
            app.MutationValue.Value = 10;
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.CrossingCheckBox = uicheckbox(app.UIFigure);
            app.CrossingCheckBox.ValueChangedFcn = createCallbackFcn(app, @CrossingCheckBoxValueChanged, true);
            app.CrossingCheckBox.Text = 'Crossing (melhor transa com todos)';
            app.CrossingCheckBox.Value = true;
            app.ControlSimulationLabel = uilabel(app.UIFigure);
            app.ControlSimulationLabel.Text = 'Control Simulation';
            app.NeutralizationCheckBox = uicheckbox(app.UIFigure);
            app.NeutralizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @NeutralizationCheckBoxValueChanged, true);
            app.NeutralizationCheckBox.Text = 'Neutralization';
            app.NeutralizationCheckBox.Value = true;
            app.NeutralizationValue = uieditfield(app.UIFigure, 'numeric');
            app.NeutralizationValue.Limits = [0 100];
            app.NeutralizationValue.ValueChangedFcn = createCallbackFcn(app, @NeutralizationValueValueChanged, true);
            app.NeutralizationValue.Value = 50;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '%';
            app.SwitchStart = uiswitch(app.UIFigure, 'slider');
            app.SwitchStart.Items = {'Stop', 'Start'};
            app.SwitchStart.ValueChangedFcn = createCallbackFcn(app, @SwitchStartValueChanged, true);
            app.SwitchStart.Value = 'Stop';
            app.SimulationStatus = uilamp(app.UIFigure);
            app.SwitchPause = uiswitch(app.UIFigure, 'slider');
            app.SwitchPause.Items = {'Pause', 'Run'};
            app.SwitchPause.ValueChangedFcn = createCallbackFcn(app, @SwitchPauseValueChanged, true);
            app.SwitchPause.Enable = 'off';
            app.SwitchPause.Value = 'Run';
            app.SavealltoPDFfileLabel = uilabel(app.UIFigure);
            app.SavealltoPDFfileLabel.Text = 'Save all to PDF file';
            app.GenesLabel = uilabel(app.UIFigure);
            app.GenesLabel.Text = 'Genes';
            app.PopulationfitnessLabel = uilabel(app.UIFigure);
            app.PopulationfitnessLabel.Text = 'Population fitness';
            app.SensorAngleLabel = uilabel(app.UIFigure);
            app.SensorAngleLabel.Text = 'Sensor Angle';
            app.ipv4Label = uilabel(app.UIFigure);
            app.ipv4Label.Text = 'ipv4:';
            app.ipv4EditField = uieditfield(app.UIFigure, 'text');
            app.ipv4EditField.Value = '192.168.15.17';
            app.ROSstatus = uilamp(app.UIFigure);
            app.ROSLabel = uilabel(app.UIFigure);
            app.ROSLabel.Text = 'ROS';
            app.SimulationLabel = uilabel(app.UIFigure);
            app.SimulationLabel.Text = 'Simulation';
            app.SavegraphimageLabel = uilabel(app.UIFigure);
            app.SavegraphimageLabel.Text = 'Save graph image';
            app.SelectGraphImage = uidropdown(app.UIFigure);
            app.SelectGraphImage.Items = {'Current Fitness', 'Average Fitness', 'Sensor Activation', 'Linear Velocity', 'Angular Velocity', 'Rotation Time', 'Sensor Angle'};
            app.SelectGraphImage.Value = 'Current Fitness';
            app.ConnectROSButton = uibutton(app.UIFigure, 'push');
            app.ConnectROSButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectROSButtonPushed, true);
            app.ConnectROSButton.Text = {'Connect ROS'; ''};
            app.SensorAngle = uiaxes(app.UIFigure);
            title(app.SensorAngle, '')
            xlabel(app.SensorAngle, 'Generation')
            ylabel(app.SensorAngle, 'Angle (degree)')
            app.SaveImage = uibutton(app.UIFigure, 'push');
            app.SaveImage.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImage.Text = 'Save';
            app.SavePDF = uibutton(app.UIFigure, 'push');
            app.SavePDF.ButtonPushedFcn = createCallbackFcn(app, @SavePDFButtonPushed, true);
            app.SavePDF.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EvolutiveSysAnalyze

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end